classdef gui_app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ToolsMenu                     matlab.ui.container.Menu
        UploadImageMenu               matlab.ui.container.Menu
        ScreenshotMenu                matlab.ui.container.Menu
        ModeSwitchMenu                matlab.ui.container.Menu
        BeginnerMenu                  matlab.ui.container.Menu
        ExpertMenu                    matlab.ui.container.Menu
        ResetMenu                     matlab.ui.container.Menu
        DMenu                         matlab.ui.container.Menu
        ForegroundMenu                matlab.ui.container.Menu
        BackgroundMenu                matlab.ui.container.Menu
        VanishPointMenu               matlab.ui.container.Menu
        DMenu_2                       matlab.ui.container.Menu
        Menu_4                        matlab.ui.container.Menu
        Menu2_3                       matlab.ui.container.Menu
        Toolbar                       matlab.ui.container.Toolbar
        PushTool2                     matlab.ui.container.toolbar.PushTool
        PushTool                      matlab.ui.container.toolbar.PushTool
        PushTool3                     matlab.ui.container.toolbar.PushTool
        PushTool4                     matlab.ui.container.toolbar.PushTool
        PushTool5                     matlab.ui.container.toolbar.PushTool
        PushTool6                     matlab.ui.container.toolbar.PushTool
        OKButton                      matlab.ui.control.Button
        GUIInstructionsTextArea       matlab.ui.control.TextArea
        GUIInstructionsTextAreaLabel  matlab.ui.control.Label
        ModeSwitchButton              matlab.ui.control.StateButton
        CommentarTextArea             matlab.ui.control.TextArea
        CommentarTextAreaLabel        matlab.ui.control.Label
        StepsPanel                    matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        Step6ScreenshotButton         matlab.ui.control.Button
        Step5AnimationButton_2        matlab.ui.control.Button
        Step43DModelButton            matlab.ui.control.Button
        Step3SelectionButton          matlab.ui.control.Button
        Step2ForegroundButton         matlab.ui.control.Button
        Step1LoadPictureButton        matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu_3                        matlab.ui.container.Menu
        Menu2_2                       matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModeSwitchButton
        function ModeSwitchButtonValueChanged2(app, event)
            value = app.ModeSwitchButton.Value;
            switch value
                case 0             
                    app.StepsPanel.Visible ="on";
                    app.CommentarTextArea.Visible ="on";
                    app.CommentarTextAreaLabel.Visible ="on";
                    app.Toolbar.Visible="off";
                    app.UIAxes.Position = [229 109 929 564];
                case 1
                    app.StepsPanel.Visible="off";
                    app.CommentarTextArea.Visible ="off";
                    app.CommentarTextAreaLabel.Visible="off";
                    app.Toolbar.Visible="on";
                    app.UIAxes.Position = [17 61 1141 612];
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.GUIInstructionsTextArea.Visible = "off";
            app.GUIInstructionsTextAreaLabel.Visible = "off";
            app.OKButton.Visible="off";
        end

        % Button pushed function: Step1LoadPictureButton
        function Step1LoadPictureButtonPushed(app, event)
            global picture;
            [f p]= uigetfile({'*.jpg;*.png','Image Files'});
            fname =[p f];
            picture = imread(fname);
            imshow(picture,'Parent',app.UIAxes);
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            global picture;
            [f p]= uigetfile({'*.jpg;*.png','Image Files'});
            fname =[p f];
            picture = imread(fname);
            imshow(picture,'Parent',app.UIAxes);
        end

        % Menu selected function: UploadImageMenu
        function UploadImageMenuSelected(app, event)
            global picture;
            [f p]= uigetfile({'*.jpg;*.png','Image Files'});
            fname =[p f];
            picture = imread(fname);
            imshow(picture,'Parent',app.UIAxes);
        end

        % Button pushed function: Step3SelectionButton
        function Step3SelectionButtonPushed(app, event)
            global picture;
            imshow(picture,'Parent',app.UIAxes);
            [vp,corners,xIntersect, yIntersect,xExtended, yExtended] = Selection(picture);
            text(app.UIAxes,vp(1), vp(2), '0', 'Color', 'yellow', 'FontSize', 20, 'FontWeight', 'bold');
            for i = 1:4
                text(app.UIAxes,corners(i, 1), corners(i, 2), num2str(i), 'Color', 'blue', 'FontSize', 20, 'FontWeight', 'bold');
            end
            %line(app.UIAxes,[vp(1), xIntersect], [vp(2), yIntersect], 'Color', 'g', 'LineWidth', 2);
            plot(app.UIAxes,xIntersect, yIntersect); % 标记交点
            %text(app.UIAxes,xIntersect, yIntersect, num2str(i + 4), 'Color', 'magenta', 'FontSize', 20, 'FontWeight', 'bold');
            %line(app.UIAxes,[corners(i, 1), xExtended], [corners(i, 2), yExtended], 'Color', 'c', 'LineWidth', 2);
            plot(app.UIAxes,xExtended, yExtended); % 标记延伸交点
            %text(app.UIAxes,xExtended, yExtended, num2str(i + 8), 'Color', 'cyan', 'FontSize', 20, 'FontWeight', 'bold');
        end

        % Button pushed function: Step2ForegroundButton
        function Step2ForegroundButtonPushed(app, event)
            global picture;
            n = 1;
            patchsize = 9;
            fillorder = 'gradient';
            [fg2D, foreground, picture] = SegmentImage(n, patchsize, fillorder,picture);
         
            imshow(picture,'Parent',app.UIAxes);

        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            global picture;
            n = 1;
            patchsize = 9;
            fillorder = 'gradient';
            [fg2D, foreground, picture] = SegmentImage(n, patchsize, fillorder,picture);
         
            imshow(picture,'Parent',app.UIAxes);

        end

        % Menu selected function: ForegroundMenu
        function ForegroundMenuSelected(app, event)
             global picture;
            n = 1;
            patchsize = 9;
            fillorder = 'gradient';
            [fg2D, foreground, picture] = SegmentImage(n, patchsize, fillorder,picture);
         
            imshow(picture,'Parent',app.UIAxes);
        end

        % Menu selected function: BeginnerMenu
        function BeginnerMenuSelected(app, event)
                    app.StepsPanel.Visible ="on";
                    app.CommentarTextArea.Visible ="on";
                    app.CommentarTextAreaLabel.Visible ="on";
                    app.Toolbar.Visible="off";
                    app.UIAxes.Position = [229 109 929 564];
        end

        % Menu selected function: ExpertMenu
        function ExpertMenuSelected(app, event)
                    app.StepsPanel.Visible="off";
                    app.CommentarTextArea.Visible ="off";
                    app.CommentarTextAreaLabel.Visible="off";
                    app.Toolbar.Visible="on";
                    app.UIAxes.Position = [17 61 1141 612];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'colorcube');
            app.UIFigure.Name = 'MATLAB App';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.UploadImageMenu = uimenu(app.ToolsMenu);
            app.UploadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @UploadImageMenuSelected, true);
            app.UploadImageMenu.Text = 'Upload Image';
            app.ScreenshotMenu = uimenu(app.ToolsMenu);
            app.ScreenshotMenu.Text = 'Screenshot';
            app.ModeSwitchMenu = uimenu(app.ToolsMenu);
            app.ModeSwitchMenu.Text = 'Mode Switch';
            app.BeginnerMenu = uimenu(app.ModeSwitchMenu);
            app.BeginnerMenu.MenuSelectedFcn = createCallbackFcn(app, @BeginnerMenuSelected, true);
            app.BeginnerMenu.Text = 'Beginner';
            app.ExpertMenu = uimenu(app.ModeSwitchMenu);
            app.ExpertMenu.MenuSelectedFcn = createCallbackFcn(app, @ExpertMenuSelected, true);
            app.ExpertMenu.Text = 'Expert';
            app.ResetMenu = uimenu(app.ToolsMenu);
            app.ResetMenu.Text = 'Reset';
            app.DMenu = uimenu(app.UIFigure);
            app.DMenu.Text = '2D';
            app.ForegroundMenu = uimenu(app.DMenu);
            app.ForegroundMenu.MenuSelectedFcn = createCallbackFcn(app, @ForegroundMenuSelected, true);
            app.ForegroundMenu.Text = 'Foreground';
            app.BackgroundMenu = uimenu(app.DMenu);
            app.BackgroundMenu.Text = 'Background';
            app.VanishPointMenu = uimenu(app.DMenu);
            app.VanishPointMenu.Text = 'Vanish Point';
            app.DMenu_2 = uimenu(app.UIFigure);
            app.DMenu_2.Text = '3D';
            app.Menu_4 = uimenu(app.DMenu_2);
            app.Menu_4.Text = 'Menu';
            app.Menu2_3 = uimenu(app.DMenu_2);
            app.Menu2_3.Text = 'Menu2';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Toolbar.Visible = 'off';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'icon', 'upload.png');
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mat-wallpaper-0072bd.png');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-stateflow-chart-0072bd.png');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Icon = fullfile(pathToMLAPP, 'icon', 'icon-fa-dropbox-0072bd.png');
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-media-video-0072bd.png');
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-photo-0072bd.png');
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.StepsPanel = uipanel(app.UIFigure);
            app.StepsPanel.Title = 'Steps';
            app.GridLayout = uigridlayout(app.StepsPanel);
            app.Step1LoadPictureButton = uibutton(app.GridLayout, 'push');
            app.Step1LoadPictureButton.ButtonPushedFcn = createCallbackFcn(app, @Step1LoadPictureButtonPushed, true);
            app.Step1LoadPictureButton.Icon = fullfile(pathToMLAPP, 'icon', 'uploadw.png');
            app.Step1LoadPictureButton.Text = {'Step 1: '; 'Load Picture'};
            app.Step2ForegroundButton = uibutton(app.GridLayout, 'push');
            app.Step2ForegroundButton.ButtonPushedFcn = createCallbackFcn(app, @Step2ForegroundButtonPushed, true);
            app.Step2ForegroundButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mat-wallpaper-ffffff-71.png');
            app.Step2ForegroundButton.Text = {'Step 2:'; 'Foreground'};
            app.Step3SelectionButton = uibutton(app.GridLayout, 'push');
            app.Step3SelectionButton.ButtonPushedFcn = createCallbackFcn(app, @Step3SelectionButtonPushed, true);
            app.Step3SelectionButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-stateflow-chart-ffffff-71.png');
            app.Step3SelectionButton.Text = {'Step 3:'; 'Selection'};
            app.Step43DModelButton = uibutton(app.GridLayout, 'push');
            app.Step43DModelButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-fa-dropbox-ffffff-71.png');
            app.Step43DModelButton.Text = {'Step 4:'; '3D Model'};
            app.Step5AnimationButton_2 = uibutton(app.GridLayout, 'push');
            app.Step5AnimationButton_2.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-media-video-ffffff-71.png');
            app.Step5AnimationButton_2.Text = {'Step 5:'; 'Animation'};
            app.Step6ScreenshotButton = uibutton(app.GridLayout, 'push');
            app.Step6ScreenshotButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-photo-ffffff-71.png');
            app.Step6ScreenshotButton.Text = {'Step 6:'; 'Screenshot'};
            app.CommentarTextAreaLabel = uilabel(app.UIFigure);
            app.CommentarTextAreaLabel.Text = 'Commentar';
            app.CommentarTextArea = uitextarea(app.UIFigure);
            app.ModeSwitchButton = uibutton(app.UIFigure, 'state');
            app.ModeSwitchButton.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchButtonValueChanged2, true);
            app.ModeSwitchButton.Text = 'Mode Switch';
            app.GUIInstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.GUIInstructionsTextAreaLabel.Text = {'GUI '; 'Instructions'};
            app.GUIInstructionsTextArea = uitextarea(app.UIFigure);
            app.GUIInstructionsTextArea.Value = {'This interface offers two modes: Beginner Mode (default) and Expert Mode. Switch modes using the button at the bottom.'; ''; '1.Beginner Mode'; 'Task Bar (Left): Follow the sequential tasks listed.'; ''; 'Image Display (Center): View related images for each task.'; ''; 'Tip Area (Bottom): Get step-by-step guidance and tips.'; ''; ''; '2.Expert Mode'; 'Icon Toolbar (Below Menu Bar): Use the tools in a left-to-right sequence.'; ''; 'Designed for experienced users with a streamlined layout.'; ''; ''; '3.Menu Bar (Top)'; 'Contains commonly used tools, including a reset tool for correcting errors or switching images.'; ''; ''; '4.Mode Switching'; 'Use the bottom button to switch between Beginner Mode and Expert Mode.'; ''; ''; ''; 'Enjoy using the GUI! '};
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu_3 = uimenu(app.ContextMenu);
            app.Menu_3.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu);
            app.Menu2_2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end